classdef CtrlGUI_LS336 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        UserInputsPanel                 matlab.ui.container.Panel
        SerialPortEditFieldLabel        matlab.ui.control.Label
        SerialPortEditField             matlab.ui.control.EditField
        ConnectionOnOffSwitchLabel      matlab.ui.control.Label
        ConnectionOnOffSwitch           matlab.ui.control.Switch
        Heater1LevelLabel               matlab.ui.control.Label
        Heater1LevelDropDown            matlab.ui.control.DropDown
        MaxCurrentALabel                matlab.ui.control.Label
        MaxCurrentASpinner              matlab.ui.control.Spinner
        HeaterOnOffSwitchLabel          matlab.ui.control.Label
        HeaterOnOffSwitch               matlab.ui.control.Switch
        Heater2LevelDropDownLabel       matlab.ui.control.Label
        Heater2LevelDropDown            matlab.ui.control.DropDown
        RefreshIntervalsSpinnerLabel    matlab.ui.control.Label
        RefreshIntervalsSpinner         matlab.ui.control.Spinner
        TargetTempKSpinnerLabel         matlab.ui.control.Label
        TargetTempKSpinner              matlab.ui.control.Spinner
        CurrentHeaterSettingsPanel      matlab.ui.container.Panel
        Heater2LampLabel                matlab.ui.control.Label
        Heater2Lamp                     matlab.ui.control.Lamp
        Heater1LampLabel                matlab.ui.control.Label
        Heater1Lamp                     matlab.ui.control.Lamp
        SerialNumberLabel               matlab.ui.control.Label
        ErrorStatLampLabel              matlab.ui.control.Label
        ErrorStatLamp                   matlab.ui.control.Lamp
        MaxCurrentAEditFieldLabel       matlab.ui.control.Label
        MaxCurrentAEditField            matlab.ui.control.NumericEditField
        TargetTempKLabel                matlab.ui.control.Label
        TargetTempKEditField            matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        CurrentStatusPanel              matlab.ui.container.Panel
        MaxPlottingPointsSpinnerLabel   matlab.ui.control.Label
        MaxPlottingPointsSpinner        matlab.ui.control.Spinner
        TabGroup                        matlab.ui.container.TabGroup
        TemperatureKTab                 matlab.ui.container.Tab
        CurrentTempGauge                matlab.ui.control.LinearGauge
        TempCurrentEditFieldLabel       matlab.ui.control.Label
        TempCurrentEditField            matlab.ui.control.NumericEditField
        TempCurrentUIAxes               matlab.ui.control.UIAxes
        HeaterPercentageTab             matlab.ui.container.Tab
        HeaterPercentageGauge           matlab.ui.control.LinearGauge
        HeaterPercentageEditFieldLabel  matlab.ui.control.Label
        HeaterPercentageEditField       matlab.ui.control.NumericEditField
        HeaterPercentageUIAxes          matlab.ui.control.UIAxes
        ClearPlotButton                 matlab.ui.control.Button
        LoggingPanel                    matlab.ui.container.Panel
        LogFileFolderEditFieldLabel     matlab.ui.control.Label
        LogFileFolderEditField          matlab.ui.control.EditField
        LoggingOnOffSwitchLabel         matlab.ui.control.Label
        LoggingOnOffSwitch              matlab.ui.control.Switch
        GoToFolderButton                matlab.ui.control.Button
        FileMenu                        matlab.ui.container.Menu
        OpenCurrentLogMenu              matlab.ui.container.Menu
        GoToLogFolderMenu               matlab.ui.container.Menu
        ManualModeMenu                  matlab.ui.container.Menu
        SendCommandDirectlyMenu         matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        Utility
        % The instance of class_Utility_LS336 that interface between driver
        % and GUI.
        
        SubGUI
        % Sub-GUI for sending explicit commands.
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");
            % Move to center.
            app.Utility = class_Utility_LS336(app);
            % Generate an instance of utility class to interact with the
            % heater controller.
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Utility.delete();
            delete(app.SubGUI)
            delete(app)            
        end

        % Value changed function: ConnectionOnOffSwitch
        function ConnectionOnOffSwitchValueChanged(app, event)
            % Connect or disconnect the controller.
            switch app.ConnectionOnOffSwitch.Value
                case 'On'
                    app.Utility.connect_Controller();
                case 'Off'
                    app.Utility.disconnect_Controller();
                    delete(app.SubGUI)
                    % Delete the sub GUI.
            end
        end

        % Value changed function: Heater1LevelDropDown, 
        % Heater2LevelDropDown, HeaterOnOffSwitch, 
        % MaxCurrentASpinner, TargetTempKSpinner
        function UserInputsValueChanged(app, event)
            % This function is run whenever the *user* changes settings of
            % heater in the User Inputs panel.
            app.Utility.send_Settings(event);
        end

        % Value changed function: RefreshIntervalsSpinner
        function RefreshIntervalsSpinnerValueChanged(app, event)
            app.Utility.change_RefreshInteval(event);
        end

        % Value changed function: LoggingOnOffSwitch
        function LoggingOnOffSwitchValueChanged(app, event)
            app.Utility.update_LogStatus(event);
        end

        % Callback function: GoToFolderButton, GoToLogFolderMenu
        function GoToFolderValueChanged(app, event)
            app.Utility.goto_Folder();
        end

        % Menu selected function: OpenCurrentLogMenu
        function OpenCurrentLogMenuSelected(app, event)
            app.Utility.goto_LogFile();
        end

        % Menu selected function: SendCommandDirectlyMenu
        function SendCommandDirectlyMenuSelected(app, event)
            app.SubGUI = CtrlGUI_LS336_Sub(app);
            % Creating the sub-GUI and pass all utilities and drivers to
            % the sub-GUI.
            app.SendCommandDirectlyMenu.Enable = 'off';
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            app.Utility.clear_Plot();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {540, 540};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {240, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Lake Shore Cyrogenic Temperature Controller MATLAB GUI';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.Tag = 'UIFigure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UserInputsPanel = uipanel(app.LeftPanel);
            app.UserInputsPanel.Title = 'User Inputs';
            app.SerialPortEditFieldLabel = uilabel(app.UserInputsPanel);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UserInputsPanel, 'text');
            app.SerialPortEditField.Tag = 'SerialPort_Port';
            app.SerialPortEditField.Tooltip = {'COM1, COM2, etc.'};
            app.SerialPortEditField.Value = 'COM4';
            app.ConnectionOnOffSwitchLabel = uilabel(app.UserInputsPanel);
            app.ConnectionOnOffSwitchLabel.Text = 'Connection On/Off';
            app.ConnectionOnOffSwitch = uiswitch(app.UserInputsPanel, 'slider');
            app.ConnectionOnOffSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectionOnOffSwitchValueChanged, true);
            app.ConnectionOnOffSwitch.Tag = 'SerialPort_ONOFF';
            app.Heater1LevelLabel = uilabel(app.UserInputsPanel);
            app.Heater1LevelLabel.Text = 'Heater 1 Level';
            app.Heater1LevelDropDown = uidropdown(app.UserInputsPanel);
            app.Heater1LevelDropDown.Items = {'Off', 'Low', 'Medium', 'High'};
            app.Heater1LevelDropDown.ValueChangedFcn = createCallbackFcn(app, @UserInputsValueChanged, true);
            app.Heater1LevelDropDown.Tag = 'Heater1Level_Set';
            app.Heater1LevelDropDown.Value = 'Off';
            app.MaxCurrentALabel = uilabel(app.UserInputsPanel);
            app.MaxCurrentALabel.Text = 'Max. Current (A)';
            app.MaxCurrentASpinner = uispinner(app.UserInputsPanel);
            app.MaxCurrentASpinner.Step = 0.1;
            app.MaxCurrentASpinner.Limits = [0 10];
            app.MaxCurrentASpinner.ValueDisplayFormat = '%11.2f';
            app.MaxCurrentASpinner.ValueChangedFcn = createCallbackFcn(app, @UserInputsValueChanged, true);
            app.MaxCurrentASpinner.Tag = 'MaxCurrent_Set';
            app.HeaterOnOffSwitchLabel = uilabel(app.UserInputsPanel);
            app.HeaterOnOffSwitchLabel.Text = 'Heater On/Off';
            app.HeaterOnOffSwitch = uiswitch(app.UserInputsPanel, 'slider');
            app.HeaterOnOffSwitch.ValueChangedFcn = createCallbackFcn(app, @UserInputsValueChanged, true);
            app.HeaterOnOffSwitch.Tag = 'HeaterONOFF_Set';
            app.Heater2LevelDropDownLabel = uilabel(app.UserInputsPanel);
            app.Heater2LevelDropDownLabel.Text = 'Heater 2 Level';
            app.Heater2LevelDropDown = uidropdown(app.UserInputsPanel);
            app.Heater2LevelDropDown.Items = {'Off', 'Low', 'Medium', 'High'};
            app.Heater2LevelDropDown.ValueChangedFcn = createCallbackFcn(app, @UserInputsValueChanged, true);
            app.Heater2LevelDropDown.Tag = 'Heater2Level_Set';
            app.Heater2LevelDropDown.Value = 'Off';
            app.RefreshIntervalsSpinnerLabel = uilabel(app.UserInputsPanel);
            app.RefreshIntervalsSpinnerLabel.Text = 'Refresh Interval (s)';
            app.RefreshIntervalsSpinner = uispinner(app.UserInputsPanel);
            app.RefreshIntervalsSpinner.Step = 5;
            app.RefreshIntervalsSpinner.Limits = [5 800];
            app.RefreshIntervalsSpinner.ValueDisplayFormat = '%11.0f';
            app.RefreshIntervalsSpinner.ValueChangedFcn = createCallbackFcn(app, @RefreshIntervalsSpinnerValueChanged, true);
            app.RefreshIntervalsSpinner.Tag = 'RefreshInterval';
            app.RefreshIntervalsSpinner.Value = 5;
            app.TargetTempKSpinnerLabel = uilabel(app.UserInputsPanel);
            app.TargetTempKSpinnerLabel.Text = 'Target Temp. (K)';
            app.TargetTempKSpinner = uispinner(app.UserInputsPanel);
            app.TargetTempKSpinner.Limits = [40 370];
            app.TargetTempKSpinner.ValueDisplayFormat = '%11.2f';
            app.TargetTempKSpinner.ValueChangedFcn = createCallbackFcn(app, @UserInputsValueChanged, true);
            app.TargetTempKSpinner.Tag = 'TempTarg_Set';
            app.TargetTempKSpinner.Value = 330;
            app.CurrentHeaterSettingsPanel = uipanel(app.LeftPanel);
            app.CurrentHeaterSettingsPanel.Title = 'Current Heater Settings';
            app.Heater2LampLabel = uilabel(app.CurrentHeaterSettingsPanel);
            app.Heater2LampLabel.Text = {'Heater 2'; ''};
            app.Heater2Lamp = uilamp(app.CurrentHeaterSettingsPanel);
            app.Heater2Lamp.Tag = 'Heater2Level';
            app.Heater2Lamp.Tooltip = {'White - Off'; 'Yellow - Low'; 'Orange - Medium'; 'Red - High'};
            app.Heater1LampLabel = uilabel(app.CurrentHeaterSettingsPanel);
            app.Heater1LampLabel.Text = 'Heater 1';
            app.Heater1Lamp = uilamp(app.CurrentHeaterSettingsPanel);
            app.Heater1Lamp.Tag = 'Heater1Level';
            app.Heater1Lamp.Tooltip = {'White - Off'; 'Yellow - Low'; 'Orange - Medium'; 'Red - High'};
            app.SerialNumberLabel = uilabel(app.CurrentHeaterSettingsPanel);
            app.SerialNumberLabel.Tag = 'SerialNumber';
            app.SerialNumberLabel.Tooltip = {'Serial Number'};
            app.SerialNumberLabel.Text = 'Serial Number';
            app.ErrorStatLampLabel = uilabel(app.CurrentHeaterSettingsPanel);
            app.ErrorStatLampLabel.Text = 'Error Stat.';
            app.ErrorStatLamp = uilamp(app.CurrentHeaterSettingsPanel);
            app.ErrorStatLamp.Tag = 'ErrCode';
            app.ErrorStatLamp.Tooltip = {'White - Off'; 'Yellow - Low'; 'Orange - Medium'; 'Red - High'};
            app.MaxCurrentAEditFieldLabel = uilabel(app.CurrentHeaterSettingsPanel);
            app.MaxCurrentAEditFieldLabel.Text = 'Max. Current (A)';
            app.MaxCurrentAEditField = uieditfield(app.CurrentHeaterSettingsPanel, 'numeric');
            app.MaxCurrentAEditField.ValueDisplayFormat = '%.2f';
            app.MaxCurrentAEditField.Editable = 'off';
            app.TargetTempKLabel = uilabel(app.CurrentHeaterSettingsPanel);
            app.TargetTempKLabel.Text = 'Target Temp. (K)';
            app.TargetTempKEditField = uieditfield(app.CurrentHeaterSettingsPanel, 'numeric');
            app.TargetTempKEditField.Limits = [0 Inf];
            app.TargetTempKEditField.ValueDisplayFormat = '%.2f';
            app.TargetTempKEditField.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.CurrentStatusPanel = uipanel(app.RightPanel);
            app.CurrentStatusPanel.Title = 'Current Status';
            app.MaxPlottingPointsSpinnerLabel = uilabel(app.CurrentStatusPanel);
            app.MaxPlottingPointsSpinnerLabel.Text = 'Max. Plotting Points';
            app.MaxPlottingPointsSpinner = uispinner(app.CurrentStatusPanel);
            app.MaxPlottingPointsSpinner.Step = 50;
            app.MaxPlottingPointsSpinner.Limits = [50 4000];
            app.MaxPlottingPointsSpinner.ValueDisplayFormat = '%.0f';
            app.MaxPlottingPointsSpinner.Value = 1000;
            app.TabGroup = uitabgroup(app.CurrentStatusPanel);
            app.TemperatureKTab = uitab(app.TabGroup);
            app.TemperatureKTab.Title = 'Temperature (K)';
            app.CurrentTempGauge = uigauge(app.TemperatureKTab, 'linear');
            app.CurrentTempGauge.Limits = [0 330];
            app.CurrentTempGauge.Tag = 'Gauge_Temp';
            app.CurrentTempGauge.Value = 298.15;
            app.TempCurrentEditFieldLabel = uilabel(app.TemperatureKTab);
            app.TempCurrentEditFieldLabel.Text = 'Mean';
            app.TempCurrentEditField = uieditfield(app.TemperatureKTab, 'numeric');
            app.TempCurrentEditField.Limits = [0 Inf];
            app.TempCurrentEditField.ValueDisplayFormat = '%.2f';
            app.TempCurrentEditField.Editable = 'off';
            app.TempCurrentEditField.Value = 298.15;
            app.TempCurrentUIAxes = uiaxes(app.TemperatureKTab);
            xlabel(app.TempCurrentUIAxes, 'Time (HH:MM)')
            ylabel(app.TempCurrentUIAxes, 'Temperature (K)')
            app.TempCurrentUIAxes.YLim = [270 330];
            app.TempCurrentUIAxes.XTick = [0 0.5 1];
            app.TempCurrentUIAxes.XTickLabel = {'0:00'; '0:30'; '1:00'};
            app.TempCurrentUIAxes.YTick = [270 285 300 315 330];
            app.TempCurrentUIAxes.YTickLabel = {'270'; '285'; '300'; '315'; '330'};
            app.TempCurrentUIAxes.Tag = 'Ax_Temp';
            app.HeaterPercentageTab = uitab(app.TabGroup);
            app.HeaterPercentageTab.Title = 'Heater Percentage (%)';
            app.HeaterPercentageGauge = uigauge(app.HeaterPercentageTab, 'linear');
            app.HeaterPercentageGauge.Tag = 'Gauge_HeaterPercentage';
            app.HeaterPercentageGauge.Value = 50;
            app.HeaterPercentageEditFieldLabel = uilabel(app.HeaterPercentageTab);
            app.HeaterPercentageEditFieldLabel.Text = 'Mean';
            app.HeaterPercentageEditField = uieditfield(app.HeaterPercentageTab, 'numeric');
            app.HeaterPercentageEditField.Limits = [0 100];
            app.HeaterPercentageEditField.ValueDisplayFormat = '%.0f';
            app.HeaterPercentageEditField.Editable = 'off';
            app.HeaterPercentageEditField.Value = 50;
            app.HeaterPercentageUIAxes = uiaxes(app.HeaterPercentageTab);
            xlabel(app.HeaterPercentageUIAxes, 'Time (HH:MM)')
            ylabel(app.HeaterPercentageUIAxes, 'Heater Percentage (%)')
            app.HeaterPercentageUIAxes.YLim = [0 100];
            app.HeaterPercentageUIAxes.XTick = [0 0.5 1];
            app.HeaterPercentageUIAxes.XTickLabel = {'0:00'; '0:30'; '1:00'};
            app.HeaterPercentageUIAxes.YTick = [0 25 50 75 100];
            app.HeaterPercentageUIAxes.YTickLabel = {'0'; '25'; '50'; '75'; '100'};
            app.HeaterPercentageUIAxes.Tag = 'Ax_HeaterPercentage';
            app.ClearPlotButton = uibutton(app.CurrentStatusPanel, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.LoggingPanel = uipanel(app.RightPanel);
            app.LoggingPanel.Title = 'Logging';
            app.LogFileFolderEditFieldLabel = uilabel(app.LoggingPanel);
            app.LogFileFolderEditFieldLabel.Text = 'Log File Folder';
            app.LogFileFolderEditField = uieditfield(app.LoggingPanel, 'text');
            app.LogFileFolderEditField.Value = 'Data';
            app.LoggingOnOffSwitchLabel = uilabel(app.LoggingPanel);
            app.LoggingOnOffSwitchLabel.Text = 'Logging On/Off';
            app.LoggingOnOffSwitch = uiswitch(app.LoggingPanel, 'slider');
            app.LoggingOnOffSwitch.ValueChangedFcn = createCallbackFcn(app, @LoggingOnOffSwitchValueChanged, true);
            app.GoToFolderButton = uibutton(app.LoggingPanel, 'push');
            app.GoToFolderButton.ButtonPushedFcn = createCallbackFcn(app, @GoToFolderValueChanged, true);
            app.GoToFolderButton.Text = 'Go To Folder';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenCurrentLogMenu = uimenu(app.FileMenu);
            app.OpenCurrentLogMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenCurrentLogMenuSelected, true);
            app.OpenCurrentLogMenu.Text = 'Open Current Log';
            app.GoToLogFolderMenu = uimenu(app.FileMenu);
            app.GoToLogFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @GoToFolderValueChanged, true);
            app.GoToLogFolderMenu.Text = 'Go To Log Folder';
            app.ManualModeMenu = uimenu(app.UIFigure);
            app.ManualModeMenu.Text = 'Manual Mode';
            app.SendCommandDirectlyMenu = uimenu(app.ManualModeMenu);
            app.SendCommandDirectlyMenu.MenuSelectedFcn = createCallbackFcn(app, @SendCommandDirectlyMenuSelected, true);
            app.SendCommandDirectlyMenu.Text = 'Send Command Directly';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CtrlGUI_LS336

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end